classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        AdditiveNoiseTab             matlab.ui.container.Tab
        GoButton_1                   matlab.ui.control.Button
        TabGroup2_2                  matlab.ui.container.TabGroup
        GaussianTab_2                matlab.ui.container.Tab
        RayleighTab_2                matlab.ui.container.Tab
        ErlangTab_2                  matlab.ui.container.Tab
        ExpTab_2                     matlab.ui.container.Tab
        UniformTab_2                 matlab.ui.container.Tab
        ImpulseTab_2                 matlab.ui.container.Tab
        MaxWindowSizeSliderLabel     matlab.ui.control.Label
        MaxWindowSizeSlider          matlab.ui.control.Slider
        AdaptiveMedianFilterLabel_2  matlab.ui.control.Label
        StartWindowSizeSliderLabel   matlab.ui.control.Label
        StartWindowSizeSlider        matlab.ui.control.Slider
        MultiplicativeNoiseTab       matlab.ui.container.Tab
        GoButton_2                   matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        GaussianTab                  matlab.ui.container.Tab
        aSpinner                     matlab.ui.control.Spinner
        aSpinner_2Label              matlab.ui.control.Label
        kSpinner                     matlab.ui.control.Spinner
        kSpinner_2Label              matlab.ui.control.Label
        bSpinner                     matlab.ui.control.Spinner
        bSpinner_2Label              matlab.ui.control.Label
        GLabel                       matlab.ui.control.Label
        DefocusedTab                 matlab.ui.container.Tab
        RSpinner                     matlab.ui.control.Spinner
        RSpinnerLabel                matlab.ui.control.Label
        GLabel_3                     matlab.ui.control.Label
        LMTab                        matlab.ui.container.Tab
        LinearMotionLabel            matlab.ui.control.Label
        GLabel_2                     matlab.ui.control.Label
        TSpinner                     matlab.ui.control.Spinner
        TSpinnerLabel                matlab.ui.control.Label
        aSpinner_2                   matlab.ui.control.Spinner
        aSpinner_2Label_2            matlab.ui.control.Label
        bSpinner_2                   matlab.ui.control.Spinner
        bSpinner_2Label_2            matlab.ui.control.Label
        CradiusSpinner               matlab.ui.control.Spinner
        CradiusSpinnerLabel          matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        SpectrumButton               matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        Label_3                      matlab.ui.control.Label
        alpSpinner                   matlab.ui.control.Spinner
        KSpinnerLabel                matlab.ui.control.Label
        KSpinner                     matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        img_reg % Description
%         img_processing
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize
            addpath(genpath('.'));
            img_H = imread('FilterFunc.png');
            imshow(img_H, 'Parent', app.UIAxes_2);

%             try
%                 app.img_processing = ImageClass('res/processing.png');
%             catch ME
%                 fprintf('%s', ME.identifier);
%             end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [f_name, f_path] = uigetfile('*.*', 'Select file');
            img_path = strcat(f_path, f_name);
            app.img_reg = ImageRegister(img_path);
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
            app.Label_2.Text = app.img_reg.getimg().getsize_char();
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            sp = app.img_reg.getimg().getspectrum();
            display_spctrm(sp);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.img_reg = app.img_reg.reset();
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
        end

        % Value changed function: StartWindowSizeSlider
        function StartWindowSizeSliderValueChanged(app, event)
            value = round(app.StartWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            app.StartWindowSizeSlider.Value = value;
            app.MaxWindowSizeSlider.Value = value;
        end

        % Value changed function: MaxWindowSizeSlider
        function MaxWindowSizeSliderValueChanged(app, event)
            value = round(app.MaxWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            if value < app.StartWindowSizeSlider.Value
                value = app.StartWindowSizeSlider.Value;
            end
            app.MaxWindowSizeSlider.Value = value;
        end

        % Button pushed function: GoButton_1
        function GoButton_1Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            switch app.TabGroup2_2.SelectedTab
                case app.ImpulseTab_2
                    v_lo = app.StartWindowSizeSlider.Value;
                    v_hi = app.MaxWindowSizeSlider.Value;
                    varargin2run = {[v_lo, v_lo], [v_hi, v_hi]};
                    f_ = @adaptive_median_3d;
%             imshow(app.img_processing.getimg_uint8(), 'Parent', app.UIAxes);
            end
            img1 = feval(f_, img0, varargin2run{:});
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
            img0 = app.img_reg.getimg().getimg_uint8();
            C = app.CradiusSpinner.Value;
            K = app.KSpinner.Value;
            alp = app.alpSpinner.Value;
            f_ = @multiplicative_inv;
            varargin2run = {[], C, -1, 0, K, alp};
            switch app.TabGroup2.SelectedTab
                case app.GaussianTab
                    k = app.kSpinner.Value;
                    a = app.aSpinner.Value;
                    b = app.bSpinner.Value;              
                    varargin2H = {k, a, b};            
                    H_f_ = @gaussian_deg;
                case app.LMTab
                    T = app.TSpinner.Value;
                    a = app.aSpinner_2.Value;
                    b = app.bSpinner_2.Value;
                    varargin2H = {T, a, b};
                    H_f_ = @linear_motion_deg;
                case app.DefocusedTab
                    R = app.RSpinner.Value;
                    varargin2H = {R};
                    H_f_ = @defocusing_deg;
            end 
            sz = app.img_reg.getimg().getsize();
            H_ = getH(sz, H_f_, varargin2H{:});
            varargin2run{1} = H_;
            img1 = feval(f_, img0, varargin2run{:});
            app.img_reg = app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdditiveNoiseTab = uitab(app.TabGroup);
            app.AdditiveNoiseTab.Title = 'Additive Noise';
            app.GoButton_1 = uibutton(app.AdditiveNoiseTab, 'push');
            app.GoButton_1.ButtonPushedFcn = createCallbackFcn(app, @GoButton_1Pushed, true);
            app.GoButton_1.Text = 'Go';
            app.TabGroup2_2 = uitabgroup(app.AdditiveNoiseTab);
            app.GaussianTab_2 = uitab(app.TabGroup2_2);
            app.GaussianTab_2.Title = 'Gaussian';
            app.RayleighTab_2 = uitab(app.TabGroup2_2);
            app.RayleighTab_2.Title = 'Rayleigh';
            app.ErlangTab_2 = uitab(app.TabGroup2_2);
            app.ErlangTab_2.Title = 'Erlang';
            app.ExpTab_2 = uitab(app.TabGroup2_2);
            app.ExpTab_2.Title = 'Exp';
            app.UniformTab_2 = uitab(app.TabGroup2_2);
            app.UniformTab_2.Title = 'Uniform';
            app.ImpulseTab_2 = uitab(app.TabGroup2_2);
            app.ImpulseTab_2.Title = 'Impulse';
            app.MaxWindowSizeSliderLabel = uilabel(app.ImpulseTab_2);
            app.MaxWindowSizeSliderLabel.Text = 'Max Window Size';
            app.MaxWindowSizeSlider = uislider(app.ImpulseTab_2);
            app.MaxWindowSizeSlider.Limits = [3 25];
            app.MaxWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.MaxWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.MaxWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MaxWindowSizeSliderValueChanged, true);
            app.MaxWindowSizeSlider.MinorTicks = [];
            app.MaxWindowSizeSlider.Value = 3;
            app.AdaptiveMedianFilterLabel_2 = uilabel(app.ImpulseTab_2);
            app.AdaptiveMedianFilterLabel_2.Text = {'Adaptive Median '; 'Filter'};
            app.StartWindowSizeSliderLabel = uilabel(app.ImpulseTab_2);
            app.StartWindowSizeSliderLabel.Text = 'Start Window Size';
            app.StartWindowSizeSlider = uislider(app.ImpulseTab_2);
            app.StartWindowSizeSlider.Limits = [3 25];
            app.StartWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.StartWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.StartWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @StartWindowSizeSliderValueChanged, true);
            app.StartWindowSizeSlider.MinorTicks = [];
            app.StartWindowSizeSlider.Value = 3;
            app.MultiplicativeNoiseTab = uitab(app.TabGroup);
            app.MultiplicativeNoiseTab.Title = 'Multiplicative Noise';
            app.GoButton_2 = uibutton(app.MultiplicativeNoiseTab, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.TabGroup2 = uitabgroup(app.MultiplicativeNoiseTab);
            app.GaussianTab = uitab(app.TabGroup2);
            app.GaussianTab.Title = 'Gaussian';
            app.aSpinner = uispinner(app.GaussianTab);
            app.aSpinner.Step = 0.0001;
            app.aSpinner.Value = 0.0001;
            app.aSpinner_2Label = uilabel(app.GaussianTab);
            app.aSpinner_2Label.Text = 'a';
            app.kSpinner = uispinner(app.GaussianTab);
            app.kSpinner.Step = 0.1;
            app.kSpinner.Value = 1;
            app.kSpinner_2Label = uilabel(app.GaussianTab);
            app.kSpinner_2Label.Text = 'k';
            app.bSpinner = uispinner(app.GaussianTab);
            app.bSpinner.Step = 0.01;
            app.bSpinner.Value = 1;
            app.bSpinner_2Label = uilabel(app.GaussianTab);
            app.bSpinner_2Label.Text = 'b';
            app.GLabel = uilabel(app.GaussianTab);
            app.GLabel.Text = {'H(u‚v) ='; ' k·exp(-a(u2+v2)ᵇ)'};
            app.DefocusedTab = uitab(app.TabGroup2);
            app.DefocusedTab.Title = 'Defocused';
            app.RSpinner = uispinner(app.DefocusedTab);
            app.RSpinner.Value = 5;
            app.RSpinnerLabel = uilabel(app.DefocusedTab);
            app.RSpinnerLabel.Text = 'R';
            app.GLabel_3 = uilabel(app.DefocusedTab);
            app.GLabel_3.Text = {'h(x‚y)=1/(πR)‚'; 'when R<√(x²+y²)'; 'else‚ 0'};
            app.LMTab = uitab(app.TabGroup2);
            app.LMTab.Title = 'LM';
            app.LinearMotionLabel = uilabel(app.LMTab);
            app.LinearMotionLabel.Text = {'Linear'; 'Motion'};
            app.GLabel_2 = uilabel(app.LMTab);
            app.GLabel_2.Text = {'H(u‚v) ='; '(T/X)(X/sin(X))·exp(jπX)'; 'where‚'; 'X=(π(ua+vb))'};
            app.TSpinner = uispinner(app.LMTab);
            app.TSpinner.Step = 0.0001;
            app.TSpinner.Value = 0.1;
            app.TSpinnerLabel = uilabel(app.LMTab);
            app.TSpinnerLabel.Text = 'T';
            app.aSpinner_2 = uispinner(app.LMTab);
            app.aSpinner_2.Step = 0.0001;
            app.aSpinner_2.Value = 0.01;
            app.aSpinner_2Label_2 = uilabel(app.LMTab);
            app.aSpinner_2Label_2.Text = 'a';
            app.bSpinner_2 = uispinner(app.LMTab);
            app.bSpinner_2.Step = 0.0001;
            app.bSpinner_2.Value = 0.01;
            app.bSpinner_2Label_2 = uilabel(app.LMTab);
            app.bSpinner_2Label_2.Text = 'b';
            app.CradiusSpinner = uispinner(app.MultiplicativeNoiseTab);
            app.CradiusSpinner.Limits = [-1 Inf];
            app.CradiusSpinner.Value = -1;
            app.CradiusSpinnerLabel = uilabel(app.MultiplicativeNoiseTab);
            app.CradiusSpinnerLabel.Text = 'C (radius)';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SpectrumButton = uibutton(app.UIFigure, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'α';
            app.alpSpinner = uispinner(app.UIFigure);
            app.alpSpinner.Step = 0.1;
            app.alpSpinner.Limits = [0 1];
            app.KSpinnerLabel = uilabel(app.UIFigure);
            app.KSpinnerLabel.Text = 'K';
            app.KSpinner = uispinner(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end